classdef Trajectory_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TrajectorydesignandsettingsTab  matlab.ui.container.Tab
        ApplyButtonPoints_MixedMov      matlab.ui.control.Button
        TrajectoryPlanningPanel         matlab.ui.container.Panel
        RunButton_MixedMov              matlab.ui.control.Button
        NumberofpointsLabel             matlab.ui.control.Label
        numberOfDesiredPoints_MixedMov  matlab.ui.control.NumericEditField
        LinearmovementstepmLabel        matlab.ui.control.Label
        LinearMovementStep              matlab.ui.control.NumericEditField
        JointmovementstepLabel          matlab.ui.control.Label
        JointMovementStep               matlab.ui.control.NumericEditField
        CurrentpositionPanel            matlab.ui.container.Panel
        XmEditFieldLabel                matlab.ui.control.Label
        XmEditField                     matlab.ui.control.NumericEditField
        YmEditFieldLabel                matlab.ui.control.Label
        YmEditField                     matlab.ui.control.NumericEditField
        ZmEditFieldLabel                matlab.ui.control.Label
        ZmEditField                     matlab.ui.control.NumericEditField
        CurrentconfigurationPanel       matlab.ui.container.Panel
        M1EditFieldLabel                matlab.ui.control.Label
        M1EditField                     matlab.ui.control.NumericEditField
        M2EditFieldLabel                matlab.ui.control.Label
        M2EditField                     matlab.ui.control.NumericEditField
        M3EditFieldLabel                matlab.ui.control.Label
        M3EditField                     matlab.ui.control.NumericEditField
        M4EditFieldLabel                matlab.ui.control.Label
        M4EditField                     matlab.ui.control.NumericEditField
        M5EditFieldLabel                matlab.ui.control.Label
        M5EditField                     matlab.ui.control.NumericEditField
        M6EditFieldLabel                matlab.ui.control.Label
        M6EditField                     matlab.ui.control.NumericEditField
        STOPButton                      matlab.ui.control.Button
        RepeatMovementButton            matlab.ui.control.StateButton
        AddpointafterButton             matlab.ui.control.Button
        RobotControlPanel               matlab.ui.container.Panel
        ConnectButton                   matlab.ui.control.Button
        DisconnectButton                matlab.ui.control.Button
        StartingPositionButton          matlab.ui.control.Button
        SimulationButton                matlab.ui.control.Button
        AddAfterEditField               matlab.ui.control.NumericEditField
        ExportButton                    matlab.ui.control.Button
        ManualcontrolTab                matlab.ui.container.Tab
        ManualcontrolSpeeddegsEditFieldLabel  matlab.ui.control.Label
        ManualcontrolSpeeddegsEditField  matlab.ui.control.NumericEditField
        PositioncontrolPanel            matlab.ui.container.Panel
        XmSliderLabel                   matlab.ui.control.Label
        XmSlider                        matlab.ui.control.Slider
        YmSliderLabel                   matlab.ui.control.Label
        YmSlider                        matlab.ui.control.Slider
        ZmSliderLabel                   matlab.ui.control.Label
        ZmSlider                        matlab.ui.control.Slider
        AddpointafterButton_Manual      matlab.ui.control.Button
        AddAfterEditField_Manual        matlab.ui.control.NumericEditField
        ConfigurationcontrolPanel       matlab.ui.container.Panel
        M1SliderLabel                   matlab.ui.control.Label
        M1Slider                        matlab.ui.control.Slider
        M2SliderLabel                   matlab.ui.control.Label
        M2Slider                        matlab.ui.control.Slider
        M3SliderLabel                   matlab.ui.control.Label
        M3Slider                        matlab.ui.control.Slider
        M4SliderLabel                   matlab.ui.control.Label
        M4Slider                        matlab.ui.control.Slider
        M5SliderLabel                   matlab.ui.control.Label
        M5Slider                        matlab.ui.control.Slider
        M6SliderLabel                   matlab.ui.control.Label
        M6Slider                        matlab.ui.control.Slider
    end

    
    properties (Access = public)       
        EditFieldsX = matlab.ui.control.NumericEditField.empty;   
        EditFieldsY = matlab.ui.control.NumericEditField.empty;
        EditFieldsZ = matlab.ui.control.NumericEditField.empty;
        XcoordinateLabel = matlab.ui.control.Label;
        YcoordinateLabel = matlab.ui.control.Label;
        ZcoordinateLabel = matlab.ui.control.Label;
        PointLabel = matlab.ui.control.Label.empty;
        
        %len pre mixedMovement        
        SpeedField = matlab.ui.control.NumericEditField.empty;
        SpeedLabel = matlab.ui.control.Label;
        MovementTypeLabel = matlab.ui.control.Label;
        MovementDropDown = matlab.ui.control.DropDown.empty;
        DeleteButtons= matlab.ui.control.Button.empty;
        RollAngleLabel = matlab.ui.control.Label;
        RollAngleField = matlab.ui.control.NumericEditField.empty;
        
        %% positions of Fields and Labels
        fieldWidth;
        fieldheight;
        offsetFieldfieldWidth;
        offsetPointLabelfieldWidth;
        fromLeftPointLabel;
        fromLeftX;
        fromLeftY;
        fromLeftZ;
        fromLeftSpeed;
        fromLeftMovementType;
        fromLeftDeleteButtons;
        fromLeftRoll;
        fromBottom;
        
        %desired Points        
        desiredPoints;        
        desiredMovTypes = cell(1);
        desiredSpeeds;
        desiredRollAngle;
        
        %settings
        stepLM; %meters
        stepJM; %degrees
        
        %for logic
        previousGripperState;
        previousRollAngle;
        currentPosition; %endEffector position
        currentConfiguration;
        PositionsConfigurationsSpeedsStructure; 
        StepSizes; %degrees        
        currentAxes;
        currentFigure;
        
        % IK
        robot;
        ik;
        weights;
        initialGuess;
        
        %Serial Communication
        serialCom;
        
        % stop
        stop;
        repeat;
        
    end
    
    methods (Access = public)
        
        function separateMovementTypes(app)             
            startingPositionCalc(app);             
            for idx = 2:size(app.desiredPoints,1)  
                if(isequal(app.desiredMovTypes{idx},'Linear'))
                    createLinearMovement(app,idx);                  
                elseif(isequal(app.desiredMovTypes{idx},'Joint'))
                    createJointMovement(app,idx);
                elseif(isequal(app.desiredMovTypes{idx},'Pick')) 
                    %pick
                    createPickAndPlaceMovement(app,idx,'Closed');
                elseif(isequal(app.desiredMovTypes{idx},'Place'))
                    %place
                    createPickAndPlaceMovement(app,idx,'Opened');
                elseif(isequal(app.desiredMovTypes{idx},'Roll'))
                    %roll
                    createRollMovement(app,idx);
                end
            end                 
        end
        
        function createLinearMovement(app,idx)               
             endEffectorPositionsLM = createLinearTrajectory(app,app.desiredPoints(idx-1,:),app.desiredPoints(idx,:));                
             configurationsLM = [];
             for i = 1: size(endEffectorPositionsLM,1)                    
                 configurationSolution = setGripperState(app,calculateIK(app,endEffectorPositionsLM(i,:)),app.previousGripperState);
                 configurationSolution = setRollAngle(app,configurationSolution,app.previousRollAngle);
                 configurationsLM = [configurationsLM; configurationSolution];                        
             end 
             JointStepSizesLM = calculateMaxJointStepSizes(app,configurationsLM);
             endEffectorPositionsLM = endEffectorPositionsLM(2:end,:); %because i am already in position defined by first element
             configurationsLM = configurationsLM(2:end,:); %because i am already in position defined by first element
             JointStepSizesLM = JointStepSizesLM(2:end);
             app.PositionsConfigurationsSpeedsStructure.Positions = [app.PositionsConfigurationsSpeedsStructure.Positions;endEffectorPositionsLM]; 
             app.PositionsConfigurationsSpeedsStructure.Configurations = [app.PositionsConfigurationsSpeedsStructure.Configurations; configurationsLM];
             app.PositionsConfigurationsSpeedsStructure.Speeds = [app.PositionsConfigurationsSpeedsStructure.Speeds;app.desiredSpeeds(idx) * ones(size(endEffectorPositionsLM,1),1)];
             app.StepSizes = [app.StepSizes;JointStepSizesLM];
        end
        
        function maxJointStepSizes = calculateMaxJointStepSizes(app,configurationsLM)
            maxJointStepSizes = 0; %first in the array is 0 because i am already in that point
            for idx = 2:size(configurationsLM,1)
                maxJointStepSizes = [maxJointStepSizes; calculateMaxJointStepSize(app,configurationsLM(idx-1,:),configurationsLM(idx,:))];
            end            
        end
        
        function maxJointStepSizeDegrees = calculateMaxJointStepSize(~,lastConfiguration,nextConfiguration)
            maxJointStepSizeRadians = max(abs([lastConfiguration.JointPosition] - [nextConfiguration.JointPosition]));
            maxJointStepSizeDegrees = rad2deg(maxJointStepSizeRadians);
        end
        
        function createPickAndPlaceMovement(app,idx,nextGripperState)                
            configurationSolution = setGripperState(app,calculateIK(app,app.desiredPoints(idx,:)),nextGripperState);  
            configurationSolution = setRollAngle(app,configurationSolution,app.previousRollAngle);
            numberOfGripperSteps = 5;
            [endEffectorPositionsJM,configurationsJM] = createJointMovementConfigurations(app,app.PositionsConfigurationsSpeedsStructure.Configurations(end,:),configurationSolution,numberOfGripperSteps); 
            endEffectorPositionsJM = endEffectorPositionsJM(2:end,:); %because i am already in position defined by first element
            configurationsJM = configurationsJM(2:end,:); %because i am already in position defined by first element
            app.PositionsConfigurationsSpeedsStructure.Configurations = [app.PositionsConfigurationsSpeedsStructure.Configurations; configurationsJM];
            app.PositionsConfigurationsSpeedsStructure.Positions = [app.PositionsConfigurationsSpeedsStructure.Positions; endEffectorPositionsJM];
            app.previousGripperState = nextGripperState;
            desiredSpeedsPaP = app.desiredSpeeds(idx) * ones(size(endEffectorPositionsJM,1),1);            
            app.PositionsConfigurationsSpeedsStructure.Speeds = [app.PositionsConfigurationsSpeedsStructure.Speeds;desiredSpeedsPaP];
            stepSizesPaP = app.stepJM * ones(size(endEffectorPositionsJM,1),1);
            app.StepSizes = [app.StepSizes;stepSizesPaP];
        end
        
        function createJointMovement(app,idx)
            configurationSolution = setGripperState(app,calculateIK(app,app.desiredPoints(idx,:)),app.previousGripperState); 
            configurationSolution = setRollAngle(app,configurationSolution,app.previousRollAngle);
            %stači zmenit tuto jedno konfiguraciu pomocou setGripperState kedze
            %predpokladam, ze predchadzajuci stav bude rovnaky
            numberOfPointsOnLine = 30;
            [endEffectorPositionsJM,configurationsJM] = createJointMovementConfigurations(app,app.PositionsConfigurationsSpeedsStructure.Configurations(end,:),configurationSolution,numberOfPointsOnLine);            
            endEffectorPositionsJM = endEffectorPositionsJM(2:end,:); %because i am already in position defined by first element
            configurationsJM = configurationsJM(2:end,:); %because i am already in position defined by first element
            app.PositionsConfigurationsSpeedsStructure.Configurations = [app.PositionsConfigurationsSpeedsStructure.Configurations; configurationsJM];
            app.PositionsConfigurationsSpeedsStructure.Positions = [app.PositionsConfigurationsSpeedsStructure.Positions; endEffectorPositionsJM];
            desiredSpeedsJM = app.desiredSpeeds(idx) * ones(size(endEffectorPositionsJM,1),1);
            app.PositionsConfigurationsSpeedsStructure.Speeds = [app.PositionsConfigurationsSpeedsStructure.Speeds;desiredSpeedsJM];
            stepSizesJM = app.stepJM * ones(size(endEffectorPositionsJM,1),1);
            app.StepSizes = [app.StepSizes;stepSizesJM];
        end
        
        function createRollMovement(app,idx)
            configurationSolution = setGripperState(app,calculateIK(app,app.desiredPoints(idx,:)),app.previousGripperState); 
            configurationSolution = setRollAngle(app,configurationSolution,app.desiredRollAngle(idx));
            %stači zmenit tuto jedno konfiguraciu pomocou setGripperState kedze
            %predpokladam, ze predchadzajuci stav bude rovnaky
            app.previousRollAngle = app.desiredRollAngle(idx);
            numberOfPointsOnLine = 30;
            [endEffectorPositionsJM,configurationsJM] = createJointMovementConfigurations(app,app.PositionsConfigurationsSpeedsStructure.Configurations(end,:),configurationSolution,numberOfPointsOnLine);            
            endEffectorPositionsJM = endEffectorPositionsJM(2:end,:); %because i am already in position defined by first element
            configurationsJM = configurationsJM(2:end,:); %because i am already in position defined by first element
            app.PositionsConfigurationsSpeedsStructure.Configurations = [app.PositionsConfigurationsSpeedsStructure.Configurations; configurationsJM];
            app.PositionsConfigurationsSpeedsStructure.Positions = [app.PositionsConfigurationsSpeedsStructure.Positions; endEffectorPositionsJM];
            desiredSpeedsJM = app.desiredSpeeds(idx) * ones(size(endEffectorPositionsJM,1),1);
            app.PositionsConfigurationsSpeedsStructure.Speeds = [app.PositionsConfigurationsSpeedsStructure.Speeds;desiredSpeedsJM];
            stepSizesJM = app.stepJM * ones(size(endEffectorPositionsJM,1),1);
            app.StepSizes = [app.StepSizes;stepSizesJM];
        end
        function configurationSolution = calculateIK(app,endEffectorPosition)            
            tform = trvec2tform(endEffectorPosition); % endEffectorPosition je vektor 1x3       
            configurationSolution = app.ik('endEffector',tform,app.weights,app.initialGuess);            
            app.initialGuess = configurationSolution;            
        end
        
        function configuration = setGripperState(~,configuration,state)           
            if(isequal(state,'Opened'))
                configuration(6).JointPosition = 0.1745;
            elseif(isequal(state,'Closed'))
                configuration(6).JointPosition = 1.2741;
            end            
        end
        
        function configuration = setGripperAngle(~,configuration,angle)        
                configuration(6).JointPosition = deg2rad(angle);   
        end
        
        function configuration  = setRollAngle(~,configuration,angle)            
            configuration(5).JointPosition = deg2rad(angle);
        end
        
        function [endEffectorPositions, configurations] = createJointMovementConfigurations(app,lastConfiguration,nextConfiguration,numberOfSteps)
            numberOfSteps = calculateNumberOfPointsOnLine(app,lastConfiguration,nextConfiguration);            
            configurations = zeros(0,0);
            endEffectorPositions = zeros(0,0);
            
            for j = 1:size(lastConfiguration,2)
                jointPositions(:,j) = linspace(lastConfiguration(j).JointPosition,nextConfiguration(j).JointPosition,numberOfSteps);
            end                
         
            for i = 1:numberOfSteps
                configurations = [configurations;lastConfiguration]; %aby som nemusel vytvarat novu strukturu, tak iba prepisem existujucu
                for idx = 1:size(lastConfiguration,2)
                    configurations(i,idx).JointPosition = jointPositions(i,idx);                        
                end                      
                endEffectorTransform = tform2trvec(getTransform(app.robot,configurations(i,:),'endEffector'));
                endEffectorPositions = [endEffectorPositions;endEffectorTransform];
            end           
        end
        
        function numberOfSteps = calculateNumberOfPointsOnLine(app,lastConfiguration,nextConfiguration)
             %abs to consider also movement of joint backwards (- difference)
             maxDifferenceRadians = max(abs([lastConfiguration.JointPosition] - [nextConfiguration.JointPosition]));
             maxDifferenceDegrees = rad2deg(maxDifferenceRadians);
             numberOfSteps = ceil(maxDifferenceDegrees/app.stepJM) + 1; 
             %round up, and +1 because i have to consider first first value
             %for example difference between 0 and 10 is 10, step is 2
             %number of steps has to be 5+1 to get vector [0 2 4 6 8 10]
        end
        
        function endEffectorPositions = createLinearTrajectory(app,startPoint,endPoint)                   
            distance = sqrt( (endPoint(1)- startPoint(1))^2 + (endPoint(2)- startPoint(2))^2 + (endPoint(3)- startPoint(3))^2 );
            numberOfPointsOnLine = ceil(distance / app.stepLM); % ceil je zaokruhlenie hore            
            xVector = linspace(startPoint(1),endPoint(1),numberOfPointsOnLine)';
            yVector = linspace(startPoint(2),endPoint(2),numberOfPointsOnLine)';
            zVector = linspace(startPoint(3),endPoint(3),numberOfPointsOnLine)';
            endEffectorPositions = [xVector,yVector,zVector]; %vektor numberOfPointsOnLinex3             
        end
        
        function plotDesiredPoints(app)
            plot3(app.currentAxes,...
                  app.desiredPoints(:,1),...
                  app.desiredPoints(:,2),...
                  app.desiredPoints(:,3),...
                  'o','Color','r','MarkerSize',8,'MarkerFaceColor','r');
        end
        
        function plotDesiredPoint(app,positionCoordinates)
            %for slider
            plot3(app.currentAxes,...
                  positionCoordinates(1),...
                  positionCoordinates(2),...
                  positionCoordinates(3),...
                  'o','Color','r','MarkerSize',8,'MarkerFaceColor','r');
        end
        
        function plotTrajectory(app)
            plot3(app.currentAxes,...
                  app.PositionsConfigurationsSpeedsStructure.Positions(:,1),...
                  app.PositionsConfigurationsSpeedsStructure.Positions(:,2),...
                  app.PositionsConfigurationsSpeedsStructure.Positions(:,3),...
                  '-o','LineWidth',1.5,'MarkerSize',3,'Color','g','MarkerFaceColor','g');
        end     
        
        function plotRobotConfiguration(app,idx)            
            show(app.robot, ...
                 app.PositionsConfigurationsSpeedsStructure.Configurations(idx,:), ...
                 'Frames','off','PreservePlot', false);                    
        end
        
        function plotRobotConfigurationManual(app,configuration)            
            %for slider
            show(app.robot, ...
                 configuration, ...
                 'Frames','off','PreservePlot', false);                    
        end
        
        function createSerialCommunication(app)            
            if (isempty(app.serialCom))
            app.serialCom = serialport("COM3",115200);
            pause(3);
            end         
        end
        
        function moveRealRobot(app,idx)
            jointString = createJointString(app,app.PositionsConfigurationsSpeedsStructure.Configurations(idx,:),app.PositionsConfigurationsSpeedsStructure.Speeds(idx));
            writeline(app.serialCom,jointString); % visualize real life  
            okString = readline(app.serialCom); % wating for response that im in position
        end
        
        function moveRealRobotManual(app,configuration,speed)
            %for slider
            jointString = createJointString(app,configuration,speed);
            writeline(app.serialCom,jointString); % visualize real life  
            okString = readline(app.serialCom); % wating for response that im in position
        end
        
        function jointString = createJointString(~,configurationSolution,speed)            
            % create Braccio string from configurationsSolution,
            %in case of j1,j2,j3 and j4 values need to be increased because
            %of the real life robot inaccuracy
            j1 = rad2deg(configurationSolution(1).JointPosition) + 8;
            j1String = int2str(j1);
            j2 = rad2deg(configurationSolution(2).JointPosition) + 4;
            j2String = int2str(j2);            
            j3 = rad2deg(configurationSolution(3).JointPosition) + 4;           
            j3String = int2str(j3);           
            j4 = rad2deg(configurationSolution(4).JointPosition) + 5;  
            j4String = int2str(j4);
            j5 = rad2deg(configurationSolution(5).JointPosition);            
            j5String = int2str(j5);
            j6 = rad2deg(configurationSolution(6).JointPosition);
            j6String = int2str(j6);
            
            coma = ',';
            speedString = int2str(speed);
            jointString = string([ 'P' j1String coma j2String coma j3String coma j4String coma j5String coma j6String coma speedString '\n']);           
        end        
        
        function updateCurrentPosAndConf(app,idx)
            app.currentPosition = app.PositionsConfigurationsSpeedsStructure.Positions(idx,:);
            app.currentConfiguration = app.PositionsConfigurationsSpeedsStructure.Configurations(idx,:);
            updateCurrentPosFields(app);
            updateCurentConfFields(app);            
        end        
        
        function updateCurrentPosAndConfManual(app,currentPosition,currentConfiguration)
            app.currentPosition = currentPosition;
            app.currentConfiguration = currentConfiguration;
            updateCurrentPosFields(app);
            updateCurentConfFields(app);
        end
        
        function updateCurrentPosFields(app)
            app.XmEditField.Value = round(app.currentPosition(1),3);
            app.YmEditField.Value = round(app.currentPosition(2),3);
            app.ZmEditField.Value = round(app.currentPosition(3),3);
        end
        
        function updateCurentConfFields(app)
            app.M1EditField.Value = round(rad2deg(app.currentConfiguration(1).JointPosition),2);
            app.M2EditField.Value = round(rad2deg(app.currentConfiguration(2).JointPosition),2);
            app.M3EditField.Value = round(rad2deg(app.currentConfiguration(3).JointPosition),2);
            app.M4EditField.Value = round(rad2deg(app.currentConfiguration(4).JointPosition),2);
            app.M5EditField.Value = round(rad2deg(app.currentConfiguration(5).JointPosition),2);
            app.M6EditField.Value = round(rad2deg(app.currentConfiguration(6).JointPosition),2);
        end        
        
        function moveModel(app)            
            while(1)                
                %forwards
                for idx = 1:size(app.PositionsConfigurationsSpeedsStructure.Configurations,1)
                    plotRobotConfiguration(app,idx);                
                    updateCurrentPosAndConf(app,idx);        
                    
                    % stop BUTTON - stop
                    drawnow %Update figures and process callbacks. Without this, in cases of smaller pauses callbacks are ignored.
                    if(app.stop == 1)
                        app.stop = 0;
                        return;                    
                    end                  
                    
                    if(idx<size(app.PositionsConfigurationsSpeedsStructure.Configurations,1))
                        pause(app.StepSizes(idx+1)/app.PositionsConfigurationsSpeedsStructure.Speeds(idx+1));%idx+1 because pause depends on speed to the next position                         
                    end          
                end
                
                % Repeat Button false
                if(app.repeat == 0)
                    app.repeat = 0;
                    return;                    
                end                
            end                    
        end      
        
        function moveRobotAndModel(app)            
            while(1)
                %forwards
                for idx = 1:size(app.PositionsConfigurationsSpeedsStructure.Configurations,1)
                    plotRobotConfiguration(app,idx);                
                    updateCurrentPosAndConf(app,idx);
                    
                    % stop BUTTON - stop
                    drawnow
                    if(app.stop == 1)
                        app.stop = 0;
                        return;                    
                    end           
                    moveRealRobot(app,idx); % doesnt need pause because of waiting for robot response that it is in position             
                end
                
                    % Repeat Button false
                    if(app.repeat == 0)
                        app.repeat = 0;
                        return;                    
                    end                                   
            end                    
        end
        
        function resetDesiredPointsFields(app)
            app.EditFieldsX.delete;
            app.EditFieldsY.delete;
            app.EditFieldsZ.delete;
            app.SpeedField.delete;
            app.XcoordinateLabel.delete;
            app.YcoordinateLabel.delete;
            app.ZcoordinateLabel.delete;
            app.PointLabel.delete;  
            app.SpeedLabel.delete;
            app.MovementTypeLabel.delete;
            app.MovementDropDown.delete;
            app.DeleteButtons.delete;
            app.RollAngleField.delete;
            app.RollAngleLabel.delete;
        end
        
        function resetDesiredPointsInternalValues(app)
            app.desiredPoints = [];
            app.desiredMovTypes = cell(1);
            app.desiredSpeeds = [];   
            app.desiredRollAngle = [];
        end
        
        function createDPLabels(app)            
            % create xLabel
            app.XcoordinateLabel = uilabel(app.TrajectoryPlanningPanel);
            app.XcoordinateLabel.Position = [app.fromLeftX+40 app.fromBottom 73 22];
            app.XcoordinateLabel.Text = 'X [m]';                      
            
            % create yLabel
            app.YcoordinateLabel = uilabel(app.TrajectoryPlanningPanel);
            app.YcoordinateLabel.Position = [app.fromLeftY+40 app.fromBottom 73 22];
            app.YcoordinateLabel.Text = 'Y [m]';                    
            
            % create zLabel
            app.ZcoordinateLabel = uilabel(app.TrajectoryPlanningPanel);
            app.ZcoordinateLabel.Position = [app.fromLeftZ+40 app.fromBottom 73 22];
            app.ZcoordinateLabel.Text = 'Z [m]';     
            
            % create SpeedLabel
            app.SpeedLabel = uilabel(app.TrajectoryPlanningPanel);
            app.SpeedLabel.Position = [app.fromLeftSpeed+11 app.fromBottom 86 22];
            app.SpeedLabel.Text = 'Speed [deg/s]';  
            
            %create MovementTypeLabel
            app.MovementTypeLabel = uilabel(app.TrajectoryPlanningPanel);
            app.MovementTypeLabel.Position = [app.fromLeftMovementType+5 app.fromBottom 86 22];
            app.MovementTypeLabel.Text = 'Movement Type'; 
            
            %create RollAngleLabel
            app.RollAngleLabel = uilabel(app.TrajectoryPlanningPanel);
            app.RollAngleLabel.Position = [app.fromLeftRoll+30 app.fromBottom 86 22];
            app.RollAngleLabel.Text = string("Wrist roll [" + char(7506) + "]"); 
            app.RollAngleLabel.Visible = 'off';
        end
        function createDPEditFields(app,idx)
                % create xField
                app.EditFieldsX(idx) = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
                app.EditFieldsX(idx).Position = [app.fromLeftX app.fromBottom app.fieldWidth app.fieldheight];                
                
                % create yField
                app.EditFieldsY(idx) = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
                app.EditFieldsY(idx).Position = [app.fromLeftY app.fromBottom app.fieldWidth app.fieldheight];                
                
                % create zField
                app.EditFieldsZ(idx) = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
                app.EditFieldsZ(idx).Position = [app.fromLeftZ app.fromBottom app.fieldWidth app.fieldheight];                             
                
                % create pointLabel
                app.PointLabel(idx) = uilabel(app.TrajectoryPlanningPanel);
                app.PointLabel(idx).Position = [app.fromLeftPointLabel app.fromBottom 55 22];
                app.PointLabel(idx).Text = string(['Point ' ,(int2str(idx))]);
                
                %create DeleteButtons
                app.DeleteButtons(idx) = uibutton(app.TrajectoryPlanningPanel, "Text", 'X');
                app.DeleteButtons(idx).Position = [app.fromLeftDeleteButtons app.fromBottom 20 20];
                app.DeleteButtons(idx).ButtonPushedFcn = @(~,~) DeleteButtonsPushed(app,idx);                 
                
                %create RollAngleField
                app.RollAngleField(idx) = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
                app.RollAngleField(idx).Visible = 'off';
                app.RollAngleField(idx).Position = [app.fromLeftRoll app.fromBottom app.fieldWidth app.fieldheight];
                app.RollAngleField(idx).Limits = [0 180];
                    
                if(idx>1)                    
                    % create speedField
                    app.SpeedField(idx) = uieditfield(app.TrajectoryPlanningPanel, 'numeric');
                    app.SpeedField(idx).Position = [app.fromLeftSpeed app.fromBottom app.fieldWidth app.fieldheight];
                    app.SpeedField(idx).Limits = [20 200];
                    
                    %create MovementTypeDropDown
                    app.MovementDropDown(idx) = uidropdown(app.TrajectoryPlanningPanel,"Items",{'Joint','Linear','Pick','Place','Roll'});
                    app.MovementDropDown(idx).Position = [app.fromLeftMovementType app.fromBottom app.fieldWidth app.fieldheight];    
                    app.MovementDropDown(idx).ValueChangedFcn = @(~,~) MovementDropDownValueChanged(app);                   
                end
        end
        
        function insertToDesiredPoints(app,idx,newDesiredPoint)
            % Insert point to position defined by idx. Examples of
            % different situations:
            
            % If idx=3 and there are 3 Points existing, newDesiredPoint will
            % be Point3 and previosPoint3 will be moved Point4.
            
            % If idx=4 and there are 3 Points existing, newDesiredPoint will
            % be just inserted as Point4.
            
            %If idx=5(and more) and there are 3 Points existing, newDesiredPoint will
            %be inserted as Point4. 
            % (If idx is greater then number of existing Points +1, 
            % idx is changed to have maximal value equal to number of existing
            % Points+1, so newDesiredPoint will be last point in the list)
            
            %If idx<1, value of idx is changed to 1
            
            if(idx<1)
                idx = 1;
            end
            
            if( idx>(size(app.desiredPoints,1)+1))
                idx =size(app.desiredPoints,1)+1;
            end
            
            moveUpDesiredPointsInternValues(app,idx);
            app.desiredPoints(idx,:) = newDesiredPoint;
            app.desiredRollAngle(idx) = 90; %default value
            if(idx>1)
                app.desiredSpeeds(idx) = 100; %default value
                app.desiredMovTypes{idx} = 'Joint'; %default value                
            end
        end
        
        function deleteFromDesiredPoints(app,idx)
            % delete point from position defined by idx            
            moveDownDesiredPointsInternValues(app,idx);               
        end
        
        function moveUpDesiredPointsInternValues(app,idx)          
            % DesiredPoints with indexes greater or equal to idx have indexes increased by 1
            % example: previous desiredpoint(idx) becomes next desiredpoint(idx+1)
            
            %There is no need to add exception because if idx is greater
            %than the size of one of three variables(for example desiredPoints)
            % app.desiredPoints(idx:end,:) returns empty vector 
            % and in that case nothing changes
            
            app.desiredPoints((idx+1):(end+1),:) = app.desiredPoints(idx:end,:);
            app.desiredSpeeds((idx+1):(end+1)) = app.desiredSpeeds(idx:end);
            app.desiredMovTypes((idx+1):(end+1)) = app.desiredMovTypes(idx:end);   
            app.desiredRollAngle((idx+1):(end+1)) = app.desiredRollAngle(idx:end);  
        end
        
        function moveDownDesiredPointsInternValues(app,idx)          
            % DesiredPoints with indexes greater or equal to idx have indexes decreased by 1
            % example: previous desiredpoint(idx) becomes next desiredpoint(idx-1)
            
            app.desiredPoints((idx):(end-1),:) = app.desiredPoints((idx+1):end,:);
            app.desiredSpeeds((idx):(end-1)) = app.desiredSpeeds((idx+1):end);
            app.desiredMovTypes((idx):(end-1)) = app.desiredMovTypes((idx+1):end);
            app.desiredRollAngle((idx):(end-1)) = app.desiredRollAngle((idx+1):end);
            
            app.desiredPoints(end,:) = [];
            app.desiredRollAngle(end) = [];
            if(idx>1)
                app.desiredSpeeds(end) = [];
                app.desiredMovTypes(end) = [];
            end
        end
        
        function createAllDesiredPointsEditFieldsAndLabels(app,numberOfPoints)
            app.fromBottom = 10; %needed to start creating from bottom 
            resetDesiredPointsFields(app);
            for idx = numberOfPoints:-1:1
                createDPEditFields(app,idx);                
                % move up from the bottom side
                app.fromBottom = app.fromBottom + app.fieldheight + 10;     
            end
            
            if(numberOfPoints>0)
                createDPLabels(app);
            end
        end
        
        function updateFieldValues(app,numberOfPoints)                       
            for idx = 1 : numberOfPoints
                app.EditFieldsX(idx).Value = app.desiredPoints(idx,1);
                app.EditFieldsY(idx).Value = app.desiredPoints(idx,2);
                app.EditFieldsZ(idx).Value = app.desiredPoints(idx,3);
                if(idx>1)
                    app.SpeedField(idx).Value = app.desiredSpeeds(idx);
                    if(isequal(app.desiredMovTypes{idx},'Joint'))
                        app.MovementDropDown(idx).Value = app.MovementDropDown(idx).Items(1);    
                    elseif(isequal(app.desiredMovTypes{idx},'Linear'))
                        app.MovementDropDown(idx).Value = app.MovementDropDown(idx).Items(2); 
                    elseif(isequal(app.desiredMovTypes{idx},'Pick'))
                        app.MovementDropDown(idx).Value = app.MovementDropDown(idx).Items(3);
                    elseif(isequal(app.desiredMovTypes{idx},'Place'))
                        app.MovementDropDown(idx).Value = app.MovementDropDown(idx).Items(4);
                    elseif(isequal(app.desiredMovTypes{idx},'Roll'))
                        app.MovementDropDown(idx).Value = app.MovementDropDown(idx).Items(5);
                    end   
                    
                    app.RollAngleField(idx).Value = app.desiredRollAngle(idx);
                    if(isequal(app.desiredMovTypes{idx},'Roll'))
                        app.RollAngleField(idx).Visible = 'on';
                        app.RollAngleLabel.Visible = 'on'; %if at least one field is visible, then also label is visible
                    else
                    app.RollAngleField(idx).Visible = 'off';
                    end
                end             
            end 
        end
        
        function updateDesiredPointsInternValues(app,numberOfPoints)
            for idx = 1 : numberOfPoints
                app.desiredPoints(idx,1) = app.EditFieldsX(idx).Value;
                app.desiredPoints(idx,2) = app.EditFieldsY(idx).Value;
                app.desiredPoints(idx,3) = app.EditFieldsZ(idx).Value;
                if(idx>1)
                    app.desiredMovTypes{idx} = app.MovementDropDown(idx).Value;
                    app.desiredSpeeds(idx) = app.SpeedField(idx).Value;                    
                end 
                app.desiredRollAngle(idx) = app.RollAngleField(idx).Value;
            end 
        end
        
        function updateDesiredPointsEditFields(app)
            numberOfPoints = size(app.desiredPoints,1);                      
            createAllDesiredPointsEditFieldsAndLabels(app,numberOfPoints);                    
            updateFieldValues(app,numberOfPoints);
        end
        
        function DeleteButtonsPushed(app,idx)       
            
            updateDesiredPointsInternValues(app,size(app.desiredPoints,1));             
            deleteFromDesiredPoints(app,idx); 
            updateDesiredPointsEditFields(app);                
            app.AddAfterEditField.Value = size(app.desiredPoints,1);
            app.AddAfterEditField_Manual.Value = size(app.desiredPoints,1);
        end
        
        function MovementDropDownValueChanged(app)            
            updateDesiredPointsInternValues(app,size(app.desiredPoints,1));
            updateDesiredPointsEditFields(app);
        end
        
        function clearPositionsConfigurationsSpeedsStructure(app)
            app.PositionsConfigurationsSpeedsStructure.Positions = [];
            app.PositionsConfigurationsSpeedsStructure.Configurations = [];
            app.PositionsConfigurationsSpeedsStructure.Speeds = []; %speed used when moving real robot to starting position, if needed
        end
        
        function startingPositionCalc(app)
            startingPosition = app.desiredPoints(1,:); 
            startingConfiguration = setGripperState(app,calculateIK(app,startingPosition),'Opened');
            startingConfiguration = setRollAngle(app,startingConfiguration,90);
            app.previousGripperState = 'Opened';
            app.previousRollAngle = 90;
            %to get actual achievable position
            position = tform2trvec(getTransform(app.robot,startingConfiguration,'endEffector'));
            %this also resets whole PositionsConfigurationsSpeedsStructure
            app.PositionsConfigurationsSpeedsStructure.Positions = position;
            app.PositionsConfigurationsSpeedsStructure.Configurations = startingConfiguration;
            app.PositionsConfigurationsSpeedsStructure.Speeds = 40; %speed used when moving real robot to starting position, if needed
            %just for model simulation
            app.StepSizes = 0; % 0 because i am already in Starting point
        end
        
        function startingPositionReCalc(app)
            %here has to be second similar funtion like StartingPosition
            %just for moveRobotToStartingPosition because i just want to
            %change first values, not reset whole PositionsConfigurationsSpeedsStructure
            startingPosition = app.desiredPoints(1,:);            
            startingConfiguration = setGripperState(app,calculateIK(app,startingPosition),'Opened'); 
            startingConfiguration = setRollAngle(app,startingConfiguration,90);
            app.previousGripperState = 'Opened';
            app.previousRollAngle = 90;
            %to get actual achievable position
            position = tform2trvec(getTransform(app.robot,startingConfiguration,'endEffector'));
            app.PositionsConfigurationsSpeedsStructure.Positions(1,:) = position;
            app.PositionsConfigurationsSpeedsStructure.Configurations(1,:) = startingConfiguration;
            app.PositionsConfigurationsSpeedsStructure.Speeds(1) = 40; %speed used when moving real robot to starting position, if needed
        end         
        
        function moveRobotToStartingPosition(app)
            %used to move real robot to the starting position since model
            %is there instantly
            updateDesiredPointsInternValues(app,1);
            startingPositionReCalc(app);
            plotRobotConfiguration(app,1);                
            updateCurrentPosAndConf(app,1);
            moveRealRobot(app,1);
        end
        
        function updateSettings(app)
            app.stepJM = app.JointMovementStep.Value;
            app.stepLM = app.LinearMovementStep.Value;
        end
        
        function exportData(app,PathAndFile)     
            numberOfPoints = size(app.desiredPoints,1);
            updateDesiredPointsInternValues(app,numberOfPoints);  
            updateSettings(app);
            separateMovementTypes(app);          
            
            x_Coord = round(app.PositionsConfigurationsSpeedsStructure.Positions(:,1),3);
            y_Coord = round(app.PositionsConfigurationsSpeedsStructure.Positions(:,2),3);
            z_Coord = round(app.PositionsConfigurationsSpeedsStructure.Positions(:,3),3);
            
            M1_Angle = [];
            M2_Angle = [];
            M3_Angle = [];
            M4_Angle = [];
            M5_Angle = [];
            M6_Angle = [];            
            for i = 1:size(app.PositionsConfigurationsSpeedsStructure.Configurations,1)
            M1_Angle = [M1_Angle ; round(app.PositionsConfigurationsSpeedsStructure.Configurations(i,1).JointPosition,3)];
            M2_Angle = [M2_Angle ; round(app.PositionsConfigurationsSpeedsStructure.Configurations(i,2).JointPosition,3)];
            M3_Angle = [M3_Angle ; round(app.PositionsConfigurationsSpeedsStructure.Configurations(i,3).JointPosition,3)];
            M4_Angle = [M4_Angle ; round(app.PositionsConfigurationsSpeedsStructure.Configurations(i,4).JointPosition,3)];
            M5_Angle = [M5_Angle ; round(app.PositionsConfigurationsSpeedsStructure.Configurations(i,5).JointPosition,3)];
            M6_Angle = [M6_Angle ; round(app.PositionsConfigurationsSpeedsStructure.Configurations(i,6).JointPosition,3)];
            end
            
            Speeds = app.PositionsConfigurationsSpeedsStructure.Speeds;
            BraccioJointStrings = strings(size(app.PositionsConfigurationsSpeedsStructure.Configurations,1),1);
            
            for i = 1:size(app.PositionsConfigurationsSpeedsStructure.Configurations,1)                
                BraccioJointStrings(i,1) = createJointString(app,app.PositionsConfigurationsSpeedsStructure.Configurations(i,:),app.PositionsConfigurationsSpeedsStructure.Speeds(i));
            end
            
            T2 = table(x_Coord,y_Coord,z_Coord,M1_Angle,M2_Angle,M3_Angle,M4_Angle,M5_Angle,M6_Angle,Speeds,BraccioJointStrings);
            T2.Properties.VariableNames = cellstr(["x (m)","y (m)","z (m)","M1 (rad)","M2 (rad)","M3 (rad)","M4 (rad)","M5 (rad)","M6 (rad)",string("Rýchlosť [" + char(7506) + "/s]"),"Braccio reťazec"]);
            
            recycle on
            delete(PathAndFile);
            writetable(T2,PathAndFile);
        end
        
        function updateSliderValues(app)
            app.M1Slider.Value = round(rad2deg(app.currentConfiguration(1).JointPosition),2);            
            app.M2Slider.Value = round(rad2deg(app.currentConfiguration(2).JointPosition),2);            
            app.M3Slider.Value = round(rad2deg(app.currentConfiguration(3).JointPosition),2);
            app.M4Slider.Value = round(rad2deg(app.currentConfiguration(4).JointPosition),2);
            app.M5Slider.Value = round(rad2deg(app.currentConfiguration(5).JointPosition),2);
            app.M6Slider.Value = round(rad2deg(app.currentConfiguration(6).JointPosition),2);
            
            app.XmSlider.Value = round(app.currentPosition(1),2);
            app.YmSlider.Value = round(app.currentPosition(2),2);
            app.ZmSlider.Value = round(app.currentPosition(3),2);
        end
        
        function SimulationfigureClose(app)
            %Tinkerkit Braccio simulation close request function
            delete(app.currentFigure);
            delete(app);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Tinkerkit Braccio control';            
            app.TrajectoryPlanningPanel.Scrollable = 'on';
            warning('off','robotics:robotmanip:joint:ResettingHomePosition')
            
            %%Positions of Fields and Labels
            app.fieldWidth = 100;
            app.fieldheight = 20;
            app.offsetFieldfieldWidth = 130;
            app.offsetPointLabelfieldWidth = 73;
            app.fromLeftPointLabel = 16;
            app.fromLeftX = app.fromLeftPointLabel + app.offsetPointLabelfieldWidth;
            app.fromLeftY = app.fromLeftX + app.offsetFieldfieldWidth;
            app.fromLeftZ = app.fromLeftX + 2*app.offsetFieldfieldWidth;
            app.fromLeftSpeed = app.fromLeftX + 3*app.offsetFieldfieldWidth;
            app.fromLeftMovementType = app.fromLeftX + 4*app.offsetFieldfieldWidth;
            app.fromLeftRoll =  app.fromLeftX + 5*app.offsetFieldfieldWidth;
            app.fromLeftDeleteButtons = app.fromLeftX + 6*app.offsetFieldfieldWidth;
            
            app.fromBottom = 10;  
            
            %Step Settings
            app.stepJM = 5;
            app.stepLM = 0.03;
            app.JointMovementStep.Value = app.stepJM;
            app.LinearMovementStep.Value = app.stepLM;
            app.JointmovementstepLabel.Text =  string("Joint movement step [" + char(7506) + "]");            
            
            %create names of numeric edit fields of Current Position and
            %configuration
            app.M1EditFieldLabel.Text = string("M1 [" + char(7506) + "]");
            app.M2EditFieldLabel.Text = string("M2 [" + char(7506) + "]");
            app.M3EditFieldLabel.Text = string("M3 [" + char(7506) + "]");
            app.M4EditFieldLabel.Text = string("M4 [" + char(7506) + "]");
            app.M5EditFieldLabel.Text = string("M5 [" + char(7506) + "]");
            app.M6EditFieldLabel.Text = string("M6 [" + char(7506) + "]");
            
            %slider configuration
            app.M1SliderLabel.Text = string("M1 [" + char(7506) + "]");
            app.M2SliderLabel.Text = string("M2 [" + char(7506) + "]");
            app.M3SliderLabel.Text = string("M3 [" + char(7506) + "]");
            app.M4SliderLabel.Text = string("M4 [" + char(7506) + "]");
            app.M5SliderLabel.Text = string("M5 [" + char(7506) + "]");
            app.M6SliderLabel.Text = string("M6 [" + char(7506) + "]");
            
            %set default values
            app.stop = 0;
            app.repeat = 0;
            
            %% Load app.robot            
            app.robot = importrobot('braccio.urdf');
            
            %% Add endEffectorBody centre (between grippers)/ TCP(tool center point)
            %%9.9cm from base, however in reality gripper jaws are not 10cm long like
            %%in simulation(model) but insted 13cm long - real TCP is 3cm from the end of jaws  
            endEffectorBody = rigidBody('endEffector');
            tform = trvec2tform([0, 0, 0.099]);
            setFixedTransform(endEffectorBody.Joint,tform);
            addBody(app.robot,endEffectorBody,'gripper_base');  
            
            %set default position
            home_Position = tform2trvec(getTransform(app.robot,homeConfiguration(app.robot),'endEffector'));
            home_Configuration = homeConfiguration(app.robot);
            updateCurrentPosAndConfManual(app,home_Position,home_Configuration);
            
            %IK 
            app.ik = robotics.InverseKinematics('RigidBodyTree',app.robot,'SolverAlgorithm','LevenbergMarquardt');
            app.weights = [0 0 0 1 1 1];
            app.initialGuess = app.robot.homeConfiguration;            
            
            %% Show app.robot in the home position            
            config = homeConfiguration(app.robot);              
            app.currentFigure = figure('Position', [800 100 700 500],'Name','Tinkerkit Braccio simulation','NumberTitle','off');       
            app.currentFigure.CloseRequestFcn = @(~,~) SimulationfigureClose(app);           
            app.currentAxes = show(app.robot,config);  
        end

        % Button pushed function: ApplyButtonPoints_MixedMov
        function ApplyButtonPoints_MixedMovPushed(app, event)
             % Mixed Movement
            numberOfPoints = floor(app.numberOfDesiredPoints_MixedMov.Value);            
            app.numberOfDesiredPoints_MixedMov.Value = numberOfPoints; %to set current value after wrong input
            createAllDesiredPointsEditFieldsAndLabels(app,numberOfPoints);
            resetDesiredPointsInternalValues(app);
            updateDesiredPointsInternValues(app,numberOfPoints);            
            app.AddAfterEditField.Value = numberOfPoints;
            app.AddAfterEditField_Manual.Value = numberOfPoints;            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Tinkerkit Braccio control window close request function
            app.stop = 1;
            if(ishandle(app.currentFigure))
                delete(app.currentFigure);
            end            
            delete(app);            
        end

        % Button pushed function: RunButton_MixedMov
        function RunButton_MixedMovPushed(app, event)
            %mixed movement 
            app.stop = 0;
            if(size(app.desiredPoints,1)>0)  
                if(~isempty(app.serialCom))
                    numberOfPoints = size(app.desiredPoints,1);
                    updateDesiredPointsInternValues(app,numberOfPoints);  
                    updateSettings(app);
                    separateMovementTypes(app);
                    cla(app.currentAxes);
                    show(app.robot, app.currentConfiguration,'Parent',app.currentAxes);
                    hold all;
                    plotDesiredPoints(app);
                    plotTrajectory(app);                
                    moveRobotAndModel(app);
                    hold off     
                end
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stop = 1;
        end

        % Value changed function: RepeatMovementButton
        function RepeatMovementButtonValueChanged(app, event)
            app.repeat = app.RepeatMovementButton.Value;            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            createSerialCommunication(app);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.serialCom = [];                       
        end

        % Button pushed function: AddpointafterButton
        function AddpointafterButtonPushed(app, event)
            idx = app.AddAfterEditField.Value + 1;
            updateDesiredPointsInternValues(app,size(app.desiredPoints,1));            
            insertToDesiredPoints(app,idx,[0.2 0.2 0.2]);            
            updateDesiredPointsEditFields(app); 
            app.AddAfterEditField.Value = size(app.desiredPoints,1);
            app.AddAfterEditField_Manual.Value = size(app.desiredPoints,1);
        end

        % Button pushed function: StartingPositionButton
        function StartingPositionButtonPushed(app, event)
            if(~isempty(app.serialCom))
                moveRobotToStartingPosition(app);
            end
        end

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
            if(size(app.desiredPoints,1)>0)
                app.stop = 0;                
                app.SimulationButton.Enable = 'off';
                app.SimulationButton.Text = 'Computing...';                
                numberOfPoints = size(app.desiredPoints,1);
                updateDesiredPointsInternValues(app,numberOfPoints);  
                updateSettings(app);
                separateMovementTypes(app);    
                app.SimulationButton.Text = 'Simulating...'; 
                cla(app.currentAxes);         
                show(app.robot, app.currentConfiguration,'Parent',app.currentAxes);
                hold all;                
                plotDesiredPoints(app);
                plotTrajectory(app); 
                axis(app.currentAxes,[-0.5 0.5 -0.5 0.5 -0.5 0.5]);
                moveModel(app);
                hold off   
                app.SimulationButton.Text = 'Simulation';
                app.SimulationButton.Enable = 'on';
            end
        end

        % Value changed function: XmSlider, YmSlider, ZmSlider
        function CoordiateSliderValueChanged(app, event)
            x = app.XmSlider.Value;
            y = app.YmSlider.Value;
            z = app.ZmSlider.Value;
            speed = app.ManualcontrolSpeeddegsEditField.Value;
            
            config = calculateIK(app,[x,y,z]);
            % to make sure that M5 and M6 angle doesnt change form previous
            % state, because initialguess is not enough to ensure this
            config = setGripperAngle(app,config,app.M6Slider.Value);
            config = setRollAngle(app,config,app.M5Slider.Value);
            %to get real achievable position
            position = tform2trvec(getTransform(app.robot,config,'endEffector'));
            
            plotRobotConfigurationManual(app,config);
            hold on
            plotDesiredPoint(app,position);            
            hold off            
            if(~isempty(app.serialCom))
                moveRealRobotManual(app,config,speed);
            end
            
            updateCurrentPosAndConfManual(app,position,config);
            updateSliderValues(app);
        end

        % Value changed function: M1Slider, M2Slider, M3Slider, 
        % M4Slider, M5Slider, M6Slider
        function ServoSliderValueChanged(app, event)
            speed = app.ManualcontrolSpeeddegsEditField.Value;            
            config = app.currentConfiguration;
            config(1).JointPosition = deg2rad(app.M1Slider.Value);
            config(2).JointPosition = deg2rad(app.M2Slider.Value);
            config(3).JointPosition = deg2rad(app.M3Slider.Value);
            config(4).JointPosition = deg2rad(app.M4Slider.Value);
            config(5).JointPosition = deg2rad(app.M5Slider.Value);
            config(6).JointPosition = deg2rad(app.M6Slider.Value);   
            
            position = tform2trvec(getTransform(app.robot,config,'endEffector'));            
            plotRobotConfigurationManual(app,config);
            hold on
            plotDesiredPoint(app,position);            
            hold off
            if(~isempty(app.serialCom))
                moveRealRobotManual(app,config,speed);
            end            
            updateCurrentPosAndConfManual(app,position,config);            
            updateSliderValues(app);
        end

        % Button pushed function: AddpointafterButton_Manual
        function AddpointafterButton_ManualPushed(app, event)
            idx = app.AddAfterEditField.Value + 1;
            updateDesiredPointsInternValues(app,size(app.desiredPoints,1));
            insertToDesiredPoints(app,idx,round(app.currentPosition,3));            
            updateDesiredPointsEditFields(app); 
            app.AddAfterEditField.Value = size(app.desiredPoints,1);
            app.AddAfterEditField_Manual.Value = size(app.desiredPoints,1);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %update values in Manual control tab, when it is clicked
            %to save reasources, values ar not updated while in tab
            %Trajectory design and settings
            selectedTab = app.TabGroup.SelectedTab;
            if(selectedTab == app.ManualcontrolTab)
                updateSliderValues(app);
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            filter = {'*.xlsx'};
            [file, path] = uiputfile(filter);           
            if((~isequal(file,0))&&(~isequal(path,0)))
                [~,~,ext] = fileparts(file);
                if(isequal(ext,'.xlsx'))
                    FileAndPath = fullfile(path,file);            
                    assignin("base","FileAndPath",FileAndPath)
                    exportData(app,FileAndPath);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TrajectorydesignandsettingsTab = uitab(app.TabGroup);
            app.TrajectorydesignandsettingsTab.Title = 'Trajectory design and settings';
            app.ApplyButtonPoints_MixedMov = uibutton(app.TrajectorydesignandsettingsTab, 'push');
            app.ApplyButtonPoints_MixedMov.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPoints_MixedMovPushed, true);
            app.ApplyButtonPoints_MixedMov.Text = 'Apply';
            app.TrajectoryPlanningPanel = uipanel(app.TrajectorydesignandsettingsTab);
            app.TrajectoryPlanningPanel.Title = 'Trajectory design';
            app.RunButton_MixedMov = uibutton(app.TrajectorydesignandsettingsTab, 'push');
            app.RunButton_MixedMov.ButtonPushedFcn = createCallbackFcn(app, @RunButton_MixedMovPushed, true);
            app.RunButton_MixedMov.Text = 'Run';
            app.NumberofpointsLabel = uilabel(app.TrajectorydesignandsettingsTab);
            app.NumberofpointsLabel.Text = 'Number of points';
            app.numberOfDesiredPoints_MixedMov = uieditfield(app.TrajectorydesignandsettingsTab, 'numeric');
            app.numberOfDesiredPoints_MixedMov.Limits = [0 Inf];
            app.LinearmovementstepmLabel = uilabel(app.TrajectorydesignandsettingsTab);
            app.LinearmovementstepmLabel.Text = 'Linear movement step [m]';
            app.LinearMovementStep = uieditfield(app.TrajectorydesignandsettingsTab, 'numeric');
            app.JointmovementstepLabel = uilabel(app.TrajectorydesignandsettingsTab);
            app.JointmovementstepLabel.Text = 'Joint movement step [';
            app.JointMovementStep = uieditfield(app.TrajectorydesignandsettingsTab, 'numeric');
            app.CurrentpositionPanel = uipanel(app.TrajectorydesignandsettingsTab);
            app.CurrentpositionPanel.Title = 'Current position';
            app.XmEditFieldLabel = uilabel(app.CurrentpositionPanel);
            app.XmEditFieldLabel.Text = 'X [m]';
            app.XmEditField = uieditfield(app.CurrentpositionPanel, 'numeric');
            app.XmEditField.Editable = 'off';
            app.YmEditFieldLabel = uilabel(app.CurrentpositionPanel);
            app.YmEditFieldLabel.Text = 'Y [m]';
            app.YmEditField = uieditfield(app.CurrentpositionPanel, 'numeric');
            app.YmEditField.Editable = 'off';
            app.ZmEditFieldLabel = uilabel(app.CurrentpositionPanel);
            app.ZmEditFieldLabel.Text = 'Z [m]';
            app.ZmEditField = uieditfield(app.CurrentpositionPanel, 'numeric');
            app.ZmEditField.Editable = 'off';
            app.CurrentconfigurationPanel = uipanel(app.TrajectorydesignandsettingsTab);
            app.CurrentconfigurationPanel.Title = 'Current configuration';
            app.M1EditFieldLabel = uilabel(app.CurrentconfigurationPanel);
            app.M1EditFieldLabel.Text = 'M1 [';
            app.M1EditField = uieditfield(app.CurrentconfigurationPanel, 'numeric');
            app.M1EditField.Editable = 'off';
            app.M2EditFieldLabel = uilabel(app.CurrentconfigurationPanel);
            app.M2EditFieldLabel.Text = 'M2 [';
            app.M2EditField = uieditfield(app.CurrentconfigurationPanel, 'numeric');
            app.M2EditField.Editable = 'off';
            app.M3EditFieldLabel = uilabel(app.CurrentconfigurationPanel);
            app.M3EditFieldLabel.Text = 'M3 [';
            app.M3EditField = uieditfield(app.CurrentconfigurationPanel, 'numeric');
            app.M3EditField.Editable = 'off';
            app.M4EditFieldLabel = uilabel(app.CurrentconfigurationPanel);
            app.M4EditFieldLabel.Text = 'M4 [';
            app.M4EditField = uieditfield(app.CurrentconfigurationPanel, 'numeric');
            app.M4EditField.Editable = 'off';
            app.M5EditFieldLabel = uilabel(app.CurrentconfigurationPanel);
            app.M5EditFieldLabel.Text = 'M5 [';
            app.M5EditField = uieditfield(app.CurrentconfigurationPanel, 'numeric');
            app.M5EditField.Editable = 'off';
            app.M6EditFieldLabel = uilabel(app.CurrentconfigurationPanel);
            app.M6EditFieldLabel.Text = 'M6 [';
            app.M6EditField = uieditfield(app.CurrentconfigurationPanel, 'numeric');
            app.M6EditField.Editable = 'off';
            app.STOPButton = uibutton(app.TrajectorydesignandsettingsTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.RepeatMovementButton = uibutton(app.TrajectorydesignandsettingsTab, 'state');
            app.RepeatMovementButton.ValueChangedFcn = createCallbackFcn(app, @RepeatMovementButtonValueChanged, true);
            app.RepeatMovementButton.Text = 'Repeat Movement';
            app.AddpointafterButton = uibutton(app.TrajectorydesignandsettingsTab, 'push');
            app.AddpointafterButton.ButtonPushedFcn = createCallbackFcn(app, @AddpointafterButtonPushed, true);
            app.AddpointafterButton.Text = 'Add point after';
            app.RobotControlPanel = uipanel(app.TrajectorydesignandsettingsTab);
            app.RobotControlPanel.Title = 'Robot Control';
            app.ConnectButton = uibutton(app.RobotControlPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.RobotControlPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.StartingPositionButton = uibutton(app.RobotControlPanel, 'push');
            app.StartingPositionButton.ButtonPushedFcn = createCallbackFcn(app, @StartingPositionButtonPushed, true);
            app.StartingPositionButton.Text = 'Starting Position';
            app.SimulationButton = uibutton(app.TrajectorydesignandsettingsTab, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.AddAfterEditField = uieditfield(app.TrajectorydesignandsettingsTab, 'numeric');
            app.AddAfterEditField.Limits = [0 Inf];
            app.AddAfterEditField.Value = 1;
            app.ExportButton = uibutton(app.TrajectorydesignandsettingsTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ManualcontrolTab = uitab(app.TabGroup);
            app.ManualcontrolTab.Title = 'Manual control';
            app.ManualcontrolSpeeddegsEditFieldLabel = uilabel(app.ManualcontrolTab);
            app.ManualcontrolSpeeddegsEditFieldLabel.Text = 'Manual control Speed  [deg/s]';
            app.ManualcontrolSpeeddegsEditField = uieditfield(app.ManualcontrolTab, 'numeric');
            app.ManualcontrolSpeeddegsEditField.Limits = [20 200];
            app.ManualcontrolSpeeddegsEditField.Value = 40;
            app.PositioncontrolPanel = uipanel(app.ManualcontrolTab);
            app.PositioncontrolPanel.Title = 'Position control';
            app.XmSliderLabel = uilabel(app.PositioncontrolPanel);
            app.XmSliderLabel.Text = 'X [m]';
            app.XmSlider = uislider(app.PositioncontrolPanel);
            app.XmSlider.Limits = [-0.41 0.41];
            app.XmSlider.MajorTicks = [-0.41 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.41];
            app.XmSlider.MajorTickLabels = {'-0.41', '-0.3', '-0.2', '0', '0', '0.1', '0.2', '0.3', '0.41'};
            app.XmSlider.ValueChangedFcn = createCallbackFcn(app, @CoordiateSliderValueChanged, true);
            app.XmSlider.MinorTicks = [-0.41 -0.4 -0.39 -0.38 -0.37 -0.36 -0.35 -0.34 -0.33 -0.32 -0.31 -0.3 -0.29 -0.28 -0.27 -0.26 -0.25 -0.24 -0.23 -0.22 -0.21 -0.2 -0.19 -0.18 -0.17 -0.16 -0.15 -0.14 -0.13 -0.12 -0.11 -0.1 -0.09 -0.08 -0.07 -0.0599999999999999 -0.05 -0.04 -0.03 -0.02 -0.00999999999999995 0 0.00999999999999995 0.02 0.03 0.04 0.05 0.0599999999999999 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41];
            app.XmSlider.Value = 0.2;
            app.YmSliderLabel = uilabel(app.PositioncontrolPanel);
            app.YmSliderLabel.Text = 'Y [m]';
            app.YmSlider = uislider(app.PositioncontrolPanel);
            app.YmSlider.Limits = [-0.41 0.41];
            app.YmSlider.MajorTicks = [-0.41 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.41];
            app.YmSlider.ValueChangedFcn = createCallbackFcn(app, @CoordiateSliderValueChanged, true);
            app.YmSlider.MinorTicks = [-0.41 -0.4 -0.39 -0.38 -0.37 -0.36 -0.35 -0.34 -0.33 -0.32 -0.31 -0.3 -0.29 -0.28 -0.27 -0.26 -0.25 -0.24 -0.23 -0.22 -0.21 -0.2 -0.19 -0.18 -0.17 -0.16 -0.15 -0.14 -0.13 -0.12 -0.11 -0.1 -0.09 -0.08 -0.07 -0.0599999999999999 -0.05 -0.04 -0.03 -0.02 -0.00999999999999995 0 0.00999999999999995 0.02 0.03 0.04 0.05 0.0599999999999999 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41];
            app.YmSlider.Value = 0.2;
            app.ZmSliderLabel = uilabel(app.PositioncontrolPanel);
            app.ZmSliderLabel.Text = 'Z [m]';
            app.ZmSlider = uislider(app.PositioncontrolPanel);
            app.ZmSlider.Limits = [-0.19 0.46];
            app.ZmSlider.MajorTicks = [-0.19 -0.1 0 0.1 0.2 0.3 0.4 0.46];
            app.ZmSlider.MajorTickLabels = {'-0.19', '-0.1', '0', '0.1', '0.2', '0.3', '0.4', '0.46'};
            app.ZmSlider.ValueChangedFcn = createCallbackFcn(app, @CoordiateSliderValueChanged, true);
            app.ZmSlider.MinorTicks = [-0.19 -0.18 -0.17 -0.16 -0.15 -0.14 -0.13 -0.12 -0.11 -0.1 -0.09 -0.08 -0.07 -0.06 -0.05 -0.04 -0.03 -0.02 -0.01 0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46];
            app.ZmSlider.Value = 0.2;
            app.AddpointafterButton_Manual = uibutton(app.PositioncontrolPanel, 'push');
            app.AddpointafterButton_Manual.ButtonPushedFcn = createCallbackFcn(app, @AddpointafterButton_ManualPushed, true);
            app.AddpointafterButton_Manual.Text = 'Add point after';
            app.AddAfterEditField_Manual = uieditfield(app.PositioncontrolPanel, 'numeric');
            app.AddAfterEditField_Manual.Limits = [0 Inf];
            app.AddAfterEditField_Manual.Value = 1;
            app.ConfigurationcontrolPanel = uipanel(app.ManualcontrolTab);
            app.ConfigurationcontrolPanel.Title = 'Configuration control';
            app.M1SliderLabel = uilabel(app.ConfigurationcontrolPanel);
            app.M1SliderLabel.Text = 'M1';
            app.M1Slider = uislider(app.ConfigurationcontrolPanel);
            app.M1Slider.Limits = [0 180];
            app.M1Slider.ValueChangedFcn = createCallbackFcn(app, @ServoSliderValueChanged, true);
            app.M2SliderLabel = uilabel(app.ConfigurationcontrolPanel);
            app.M2SliderLabel.Text = 'M2';
            app.M2Slider = uislider(app.ConfigurationcontrolPanel);
            app.M2Slider.Limits = [15 165];
            app.M2Slider.ValueChangedFcn = createCallbackFcn(app, @ServoSliderValueChanged, true);
            app.M2Slider.Value = 15;
            app.M3SliderLabel = uilabel(app.ConfigurationcontrolPanel);
            app.M3SliderLabel.Text = 'M3';
            app.M3Slider = uislider(app.ConfigurationcontrolPanel);
            app.M3Slider.Limits = [0 180];
            app.M3Slider.ValueChangedFcn = createCallbackFcn(app, @ServoSliderValueChanged, true);
            app.M4SliderLabel = uilabel(app.ConfigurationcontrolPanel);
            app.M4SliderLabel.Text = 'M4';
            app.M4Slider = uislider(app.ConfigurationcontrolPanel);
            app.M4Slider.Limits = [0 180];
            app.M4Slider.ValueChangedFcn = createCallbackFcn(app, @ServoSliderValueChanged, true);
            app.M5SliderLabel = uilabel(app.ConfigurationcontrolPanel);
            app.M5SliderLabel.Text = 'M5';
            app.M5Slider = uislider(app.ConfigurationcontrolPanel);
            app.M5Slider.Limits = [0 180];
            app.M5Slider.ValueChangedFcn = createCallbackFcn(app, @ServoSliderValueChanged, true);
            app.M6SliderLabel = uilabel(app.ConfigurationcontrolPanel);
            app.M6SliderLabel.Text = 'M6';
            app.M6Slider = uislider(app.ConfigurationcontrolPanel);
            app.M6Slider.Limits = [10 73];
            app.M6Slider.ValueChangedFcn = createCallbackFcn(app, @ServoSliderValueChanged, true);
            app.M6Slider.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trajectory_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end